classdef SwallowEMG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        QuitButton        matlab.ui.control.Button
        StatusLamp        matlab.ui.control.Lamp
        ReadyButton       matlab.ui.control.Button
        InstructionLabel  matlab.ui.control.Label
        TrainerLabel      matlab.ui.control.Label
    end

    
    % Properties to manage the recording state
    properties (Access = private)
        IsRecording = false; % True when recording is active
        CycleCount = 0;      % To count the number of swallows
        DefaultButtonColor;  % To store the button's original color
        ButtonPressed = false; % Track if button is being pressed
    end
    
    methods (Access = private)
        
        % Empty function to override default button behavior
        function doNothing(app, src, event)
            % This prevents the default button click behavior
        end

        % Mouse pressed anywhere in the window
        function mousePressed(app, src, event)
            % Check if the mouse is over the Ready button
            if app.isMouseOverButton()
                app.ButtonPressed = true;
                % Start recording
                app.startRecording();
            end
        end
        
        % Mouse released anywhere in the window
        function mouseReleased(app, src, event)
            % Stop recording if button was pressed and recording is active
            if app.ButtonPressed && app.IsRecording
                app.ButtonPressed = false;
                app.stopRecording();
            end
        end
        
        % Mouse moved - check if mouse left the button area
        function mouseMoved(app, src, event)
            % If button was pressed but mouse moved away, stop recording
            if app.ButtonPressed && ~app.isMouseOverButton()
                app.ButtonPressed = false;
                if app.IsRecording
                    app.stopRecording();
                end
            end
        end
        
        % Helper function to check if mouse is over the Ready button
        function isOver = isMouseOverButton(app)
            mousePos = app.UIFigure.CurrentPoint;
            buttonPos = app.ReadyButton.Position;
            
            isOver = mousePos(1) >= buttonPos(1) && ...
                     mousePos(1) <= buttonPos(1) + buttonPos(3) && ...
                     mousePos(2) >= buttonPos(2) && ...
                     mousePos(2) <= buttonPos(2) + buttonPos(4);
        end
        
        % Start recording function
        function startRecording(app)
            if ~app.IsRecording
                app.IsRecording = true;
                
                % --- UI FEEDBACK FOR STARTING ---
                app.InstructionLabel.Text = 'Recording... Release when done.';
                app.ReadyButton.Text = 'Recording...';
                app.ReadyButton.BackgroundColor = [0.85, 0.33, 0.10]; % Orange-red color
                
                % Set lamp color and ensure it stays visible
                app.StatusLamp.Color = [1.0, 0.0, 0.0]; % Red for 'on'
                app.StatusLamp.Enable = 'on'; % Ensure lamp is enabled
                
                % Force UI update
                drawnow limitrate; % Use limitrate to prevent excessive updates
                
                % ---!!!--- YOUR EMG CODE GOES HERE ---!!!---
                disp('--- EMG Recording Started ---');
                % start(app.DaqSession, 'continuous');
                % ---!!!--- END OF EMG CODE SECTION ---!!!---
            end
        end
        
        % Stop recording function
        function stopRecording(app)
            if app.IsRecording
                app.IsRecording = false;
                app.CycleCount = app.CycleCount + 1;
                
                % --- UI FEEDBACK FOR STOPPING ---
                app.InstructionLabel.Text = sprintf('Cycle %d recorded! Ready for next swallow.', app.CycleCount);
                app.ReadyButton.Text = 'I''m Ready';
                app.ReadyButton.BackgroundColor = app.DefaultButtonColor;
                
                % Set lamp to green for success
                app.StatusLamp.Color = [0.0, 1.0, 0.0]; % Green for 'success'
                app.StatusLamp.Enable = 'on'; % Ensure lamp is enabled
                
                % Force UI update
                drawnow limitrate;
                
                % ---!!!--- YOUR EMG CODE GOES HERE ---!!!---
                disp('--- EMG Recording Stopped ---');
                fprintf('Cycle %d complete.\n', app.CycleCount);
                % stop(app.DaqSession);
                % data = read(app.DaqSession, app.DaqSession.ScansAcquired);
                % processSwallowData(data);
                % ---!!!--- END OF EMG CODE SECTION ---!!!---
                
                % Reset lamp after delay using a timer (non-blocking)
                t = timer('ExecutionMode', 'singleShot', ...
                         'StartDelay', 1.5, ...
                         'TimerFcn', @(~,~) app.resetLamp());
                start(t);
            end
        end
        
        % Reset the lamp to grey
        function resetLamp(app)
            if isvalid(app) && isvalid(app.StatusLamp)
                app.StatusLamp.Color = [0.8, 0.8, 0.8];
                drawnow limitrate;
            end
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Store the default button color so we can reset it later
            app.DefaultButtonColor = app.ReadyButton.BackgroundColor;

            % Initial state message
            app.InstructionLabel.Text = 'Press and hold ''I''m Ready'' to record a swallow.';
            app.StatusLamp.Color = [0.8 0.8 0.8]; % Grey color for 'off'
            
            % Disable the ready button's default click behavior
            app.ReadyButton.Enable = 'on';
            app.ReadyButton.ButtonPushedFcn = @app.doNothing; % Empty function to prevent default behavior
            
            % Set up the window-level mouse events for press-and-hold functionality
            app.UIFigure.WindowButtonDownFcn = @app.mousePressed;
            app.UIFigure.WindowButtonUpFcn = @app.mouseReleased;
            app.UIFigure.WindowButtonMotionFcn = @app.mouseMoved;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % ---!!!--- CLEANUP CODE ---!!!---
            disp('Quitting application.');
            % Stop any ongoing recording
            if app.IsRecording
                app.stopRecording();
            end
            % release(app.DaqDevice);
            % ---!!!--- END OF CLEANUP ---!!!---
            
            % Close the app
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainerLabel = uilabel(app.UIFigure);
            app.TrainerLabel.Text = 'Trainer';
            app.InstructionLabel = uilabel(app.UIFigure);
            app.InstructionLabel.Text = 'Complete a Regular swallow.';
            app.ReadyButton = uibutton(app.UIFigure, 'push');
            app.ReadyButton.Text = 'I''m Ready';
            app.StatusLamp = uilamp(app.UIFigure);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit Session';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwallowEMG_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end