classdef SwallowEMG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        QuitButton        matlab.ui.control.Button
        StatusLamp        matlab.ui.control.Lamp
        ReadyButton       matlab.ui.control.Button
        InstructionLabel  matlab.ui.control.Label
        TrainerLabel      matlab.ui.control.Label
        EMGAxes           matlab.ui.control.UIAxes
    end

    
    % Properties to manage the recording state
    properties (Access = private)
        IsRecording = false; % True when recording is active
        CycleCount = 0;      % To count the number of swallows
        DefaultButtonColor;  % To store the button's original color
        ButtonPressed = false; % Track if button is being pressed
        PlotHandle;          % Handle to the line object on the axes
        SimulatedData;       % Stores the data points for plotting
        DataBufferLength = 200; % Number of data points to display at once
        PlotTimer;           % Timer object for continuous plotting updates
    end
    
    methods (Access = private)
        
        % Empty function to override default button behavior
        function doNothing(app, src, event)
            % This prevents the default button click behavior
        end

        % Mouse pressed anywhere in the window
        function mousePressed(app, src, event)
            % Check if the mouse is over the Ready button
            if app.isMouseOverButton()
                app.ButtonPressed = true;
                % Start recording
                app.startRecording();
            end
        end
        
        % Mouse released anywhere in the window
        function mouseReleased(app, src, event)
            % Stop recording if button was pressed and recording is active
            if app.ButtonPressed && app.IsRecording
                app.ButtonPressed = false;
                app.stopRecording();
            end
        end
        
        % Mouse moved - check if mouse left the button area
        function mouseMoved(app, src, event)
            % If button was pressed but mouse moved away, stop recording
            if app.ButtonPressed && ~app.isMouseOverButton()
                app.ButtonPressed = false;
                if app.IsRecording
                    app.stopRecording();
                end
            end
        end
        
        % Helper function to check if mouse is over the Ready button
        function isOver = isMouseOverButton(app)
            mousePos = app.UIFigure.CurrentPoint;
            buttonPos = app.ReadyButton.Position;
            
            isOver = mousePos(1) >= buttonPos(1) && ...
                     mousePos(1) <= buttonPos(1) + buttonPos(3) && ...
                     mousePos(2) >= buttonPos(2) && ...
                     mousePos(2) <= buttonPos(2) + buttonPos(4);
        end
        
        % Start recording function
        function startRecording(app)
            if ~app.IsRecording
                app.IsRecording = true;
                
                % --- UI FEEDBACK FOR STARTING ---
                app.InstructionLabel.Text = 'Recording... Release when done.';
                app.ReadyButton.Text = 'Recording...';
                app.ReadyButton.BackgroundColor = [0.85, 0.33, 0.10]; % Orange-red color
                
                % Set lamp color and ensure it stays visible
                app.StatusLamp.Color = [1.0, 0.0, 0.0]; % Red for 'on'
                app.StatusLamp.Enable = 'on'; % Ensure lamp is enabled
                
                % Force UI update
                drawnow limitrate; % Use limitrate to prevent excessive updates
                
                % ---!!!--- YOUR EMG CODE GOES HERE ---!!!---
                disp('--- EMG Recording Started ---');
                % start(app.DaqSession, 'continuous');
                % ---!!!--- END OF EMG CODE SECTION ---!!!---
            end
        end
        
        % Stop recording function
        function stopRecording(app)
            if app.IsRecording
                app.IsRecording = false;
                app.CycleCount = app.CycleCount + 1;
                
                % --- UI FEEDBACK FOR STOPPING ---
                app.InstructionLabel.Text = sprintf('Cycle %d recorded! Ready for next swallow.', app.CycleCount);
                app.ReadyButton.Text = 'I''m Ready';
                app.ReadyButton.BackgroundColor = app.DefaultButtonColor;
                
                % Set lamp to green for success
                app.StatusLamp.Color = [0.0, 1.0, 0.0]; % Green for 'success'
                app.StatusLamp.Enable = 'on'; % Ensure lamp is enabled
                
                % Force UI update
                drawnow limitrate;
                
                % ---!!!--- YOUR EMG CODE GOES HERE ---!!!---
                disp('--- EMG Recording Stopped ---');
                fprintf('Cycle %d complete.\n', app.CycleCount);
                % stop(app.DaqSession);
                % data = read(app.DaqSession, app.DaqSession.ScansAcquired);
                % processSwallowData(data);
                % ---!!!--- END OF EMG CODE SECTION ---!!!---
                
                % Reset lamp after delay using a timer (non-blocking)
                t = timer('ExecutionMode', 'singleShot', ...
                         'StartDelay', 1.5, ...
                         'TimerFcn', @(~,~) app.resetLamp());
                start(t);
            end
        end
        
        % Reset the lamp to grey
        function resetLamp(app)
            if isvalid(app) && isvalid(app.StatusLamp)
                app.StatusLamp.Color = [0.8, 0.8, 0.8];
                drawnow limitrate;
            end
        end

        % Function to update the real-time EMG plot
        function updateEMGPlot(app)
            % ---!!!--- REPLACE THIS WITH YOUR ACTUAL EMG DATA ACQUISITION ---!!!---
            % This is a simulation:
            % Generate a random value around 0 for noise
            newValue = (rand() - 0.5) * 0.2;

            % Add a "swallow" peak if recording is active
            if app.IsRecording
                % Simulate a more pronounced signal during "recording"
                % Using a sine wave for a peak, combined with some randomness
                t = now * 10000; % A time-like variable for the sine wave
                swallowPeak = sin(t * 0.01) * 0.5 + 0.5;
                newValue = newValue + swallowPeak * (rand() * 0.5 + 0.5);
            end
            % ---!!!--- END OF SIMULATION ---!!!---

            % Add the new value to the buffer and remove the oldest
            app.SimulatedData = [app.SimulatedData(2:end), newValue];

            % Update the plot data
            set(app.PlotHandle, 'YData', app.SimulatedData);

            % Force UI update (important for smooth animation)
            drawnow limitrate;
        end
        
        % --- ADD THIS NEW METHOD FOR CLEANUP ---
        % Callback function executed when the UIFigure is requested to close
        function UIFigureCloseRequest(app, event)
            % Stop and delete the plot timer
            if isvalid(app.PlotTimer) && strcmp(app.PlotTimer.Running, 'on')
                stop(app.PlotTimer);
            end
            if isvalid(app.PlotTimer)
                delete(app.PlotTimer);
            end

            % ---!!!--- ADD ANY OTHER CLEANUP CODE HERE ---!!!---
            % For example, if you had a DaqSession object:
            % if isvalid(app.DaqSession)
            %     stop(app.DaqSession);
            %     release(app.DaqSession);
            % end
            % ---!!!--- END OF YOUR OTHER CLEANUP CODE ---!!!---

            % Finally, delete the figure itself
            delete(app.UIFigure);
        end
        % --- END OF NEW METHOD ---
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Store the default button color so we can reset it later
            app.DefaultButtonColor = app.ReadyButton.BackgroundColor;
            % Initial state message
            app.InstructionLabel.Text = 'Press and hold ''I''m Ready'' to record a swallow.';
            app.StatusLamp.Color = [0.8 0.8 0.8]; % Grey color for 'off'
            % Disable the ready button's default click behavior
            app.ReadyButton.Enable = 'on';
            app.ReadyButton.ButtonPushedFcn = @app.doNothing; % Empty function to prevent default behavior
            % Set up the window-level mouse events for press-and-hold functionality
            app.UIFigure.WindowButtonDownFcn = @app.mousePressed;
            app.UIFigure.WindowButtonUpFcn = @app.mouseReleased;
            app.UIFigure.WindowButtonMotionFcn = @app.mouseMoved;

            % --- ADD THESE LINES FOR PLOTTING INITIALIZATION AND FIGURE CLEANUP ---

            % Initialize EMGAxes properties (since createComponents is protected)
            app.EMGAxes.XLim = [0 app.DataBufferLength]; % Set X-axis limits
            app.EMGAxes.YLim = [-1 1]; % Adjust Y-axis limits based on your expected EMG signal range
            app.EMGAxes.Box = 'on';
            app.EMGAxes.XGrid = 'on';
            app.EMGAxes.YGrid = 'on';
            app.EMGAxes.Title.String = 'Real-time EMG Signal';
            app.EMGAxes.XLabel.String = 'Time (samples)'; % Or 'Time (ms)' if you track time
            app.EMGAxes.YLabel.String = 'Amplitude';
            % Note: Positioning is usually handled by App Designer's layout,
            % but you can set it here if needed and it's not locked.
            % app.EMGAxes.Position = [250 120 350 250]; % Uncomment and adjust if you need to override layout

            % Initialize data buffer
            app.SimulatedData = zeros(1, app.DataBufferLength);
            % Create an initial plot line on the EMGAxes
            app.PlotHandle = plot(app.EMGAxes, app.SimulatedData, 'b-');

            % Create a timer for continuous plot updates
            app.PlotTimer = timer(...
                'ExecutionMode', 'fixedRate', ... % Execute at fixed intervals
                'Period', 0.05, ...             % Update every 50 ms (20 Hz)
                'TimerFcn', @(~,~) app.updateEMGPlot() ...
            );
            % Start the timer immediately to show baseline signal
            start(app.PlotTimer);

            % Set the CloseRequestFcn for proper cleanup when the figure is closed
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % ---!!!--- CLEANUP CODE ---!!!---
            disp('Quitting application.');
            % Stop any ongoing recording
            if app.IsRecording
                app.stopRecording();
            end
            % release(app.DaqDevice);
            % ---!!!--- END OF CLEANUP ---!!!---
            
            % Close the app
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EMGAxes = uiaxes(app.UIFigure);
            title(app.EMGAxes, 'Real-time EMG Signal')
            xlabel(app.EMGAxes, 'Time (samples)')
            ylabel(app.EMGAxes, 'Amplitude')
            zlabel(app.EMGAxes, 'Z')
            app.TrainerLabel = uilabel(app.UIFigure);
            app.TrainerLabel.Text = 'Trainer';
            app.InstructionLabel = uilabel(app.UIFigure);
            app.InstructionLabel.Text = 'Complete a Regular swallow.';
            app.ReadyButton = uibutton(app.UIFigure, 'push');
            app.ReadyButton.Text = 'I''m Ready';
            app.StatusLamp = uilamp(app.UIFigure);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit Session';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwallowEMG_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end